classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalibrateInputChannel1Button  matlab.ui.control.Button
        UITime                        matlab.ui.control.UIAxes
        UIAutospectrum                matlab.ui.control.UIAxes
        LogTextAreaLabel              matlab.ui.control.Label
        LogTextArea                   matlab.ui.control.TextArea
    end


    methods (Access = private)
            
        function Feedback(app, src, event)
            s = sprintf('%s - %s', string(event.MessageType), string(event.Message));
            app.LogTextArea.Value = [app.LogTextArea.Value; s];
            
            drawnow;
        end
        
       function CalibrationResultsUpdated(app, src, event)
           
            if (isempty(event.TimeAxis) == 0)
                x = double(event.TimeAxis);
                plot(app.UITime, x, double(event.TimeBlock));            
                xLL = x(1);
                xUL = x(length(x));
                app.UITime.XLim = [xLL xUL];             
            end
               
            s = sprintf('Time - Time elapsed (s): %.2f', event.CurrentTimeElapsed);
            app.UITime.Title.String = s;
            
            if (isempty(event.FrequencyAxis) == 0)        
                plot(app.UIAutospectrum, double(event.FrequencyAxis), double(event.ResponseAutoSpectrum));    
                app.UIAutospectrum.XLim = [500 1500];
            end
   
            s = sprintf('Averaged Autospectrum - Average: %d/%d', event.CurrentAverageNumber,event.TotalAverages);
            app.UIAutospectrum.Title.String = s;
     
            drawnow;
       end
     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrateInputChannel1Button
        function CalibrateInputChannel1ButtonPushed(app, event)
       
            % EA Engine installation path
            installPath = 'C:\Program Files\Bruel and Kjaer\EA Engine';
            % EA Engine executable filename (including full path)
            assemblyName = 'C:\Program Files\Bruel and Kjaer\EA Engine\EA_Engine.exe';

            % Add the EA Engine .NET assembly (please note that it cannot be removed from the current MATLAB session until exiting)
            EA_Engine = NET.addAssembly(assemblyName);
            % Import the assembly namespace
            import EA_Engine.*;

            % Declare and instanciate a new Engine object (the installation path is mandatory)
            engine = Engine(installPath);

            % Listeners for a .NET object (here the engine object)
            % Events are:
            % - Feedback: returns information during a task execution
            % - CalibrationResultsUpdated: returns the updated results during the measurement (concerns only input or output callibration, the other tests don't raise this event)
            % - TestResultsAvailable: returns the test results once the acquisition and processing are finished (concerns all the tasks)
            lh_Feedback = addlistener(engine, 'Feedback', @app.Feedback);
            lh_CalibrationResultsUpdated = addlistener(engine,'CalibrationResultsUpdated',@app.CalibrationResultsUpdated);
         
            % Set the input calibration settings
            calibrationSettings = engine.SetInputCalibrationSettings(1000, 94, 'dB', 10, ResultFileFormatTypes.CSV, true, '2,3', 8192);

            % Define the arguments for the first input channel calibration (see user's manual for details)
            arguments = sprintf('%s %s', 'Calibrate_Input_Channel', '1');
            % Execute the task
            engine.Execute(arguments);

            % Delete the listeners once the task is finished
            delete (lh_Feedback);
            delete (lh_CalibrationResultsUpdated);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalibrateInputChannel1Button = uibutton(app.UIFigure, 'push');
            app.CalibrateInputChannel1Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrateInputChannel1ButtonPushed, true);
            app.CalibrateInputChannel1Button.Text = 'Calibrate Input Channel 1 ';
            app.UITime = uiaxes(app.UIFigure);
            title(app.UITime, 'Time Response')
            xlabel(app.UITime, 'Time [s]')
            ylabel(app.UITime, 'Real [Pa]')
            app.UIAutospectrum = uiaxes(app.UIFigure);
            title(app.UIAutospectrum, 'Averaged Autospectrum')
            xlabel(app.UIAutospectrum, 'Frequency [Hz]')
            ylabel(app.UIAutospectrum, 'Amplitude [Pa rms]')
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end